classdef number_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        ZaaanalyzuButton              matlab.ui.control.Button
        VypracovalaAnzhelikaNikolaieva2026Label  matlab.ui.control.Label
        DFTDCTDWTporovnaniesparsityakvalityrekontrukcieLabel  matlab.ui.control.Label
        BakalrskaprcaAnalzasparsitysignlovvDOTLabel  matlab.ui.control.Label
        Panel_2                       matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Panel_3                       matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        VybertetypzobrazeniaDropDown  matlab.ui.control.DropDown
        VybertetypzobrazeniaDropDownLabel  matlab.ui.control.Label
        VbersignluDropDown            matlab.ui.control.DropDown
        VbersignluDropDownLabel       matlab.ui.control.Label
        VybertetransformciuDropDown   matlab.ui.control.DropDown
        VybertetransformciuDropDownLabel  matlab.ui.control.Label
        VyberteSNREditField           matlab.ui.control.NumericEditField
        VyberteSNREditFieldLabel      matlab.ui.control.Label
        VybertePRDEditField           matlab.ui.control.NumericEditField
        VybertePRDEditFieldLabel      matlab.ui.control.Label
        SpustiButton                  matlab.ui.control.Button
        VberzvoduSpinner_2            matlab.ui.control.Spinner
        VberzvoduSpinner_2Label       matlab.ui.control.Label
        VsledkyPanel                  matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Signals % štruktúra pre všetky signály (combined_signal, reconstructed_signal atď.)
        CurrentSignal % tu sa ukladá vybraný signál
        CurrentFile   % názov súboru, z ktorého sa súbor načítal
        LastMetricUsed char = ''   % zapamätáme si, čo bolo zadané ako prvé (PRD alebo SNR)
    end
    
    methods (Access = private)
    
    % DFT
    function computeDFT(app, signal, channel, desired_PRD, desired_SNR)

    % Kontrola dĺžky signálu
    max_length = 3000; 
    if size(signal,1) < max_length      % ak je v signále menej riadkov (=odpočtov)
        uialert(app.UIFigure, 'Signál je kratší než 3000 vzoriek!', 'Chyba');
        return;
    end

    % Príprava údajov
    combined_signal = signal(1:max_length, channel);
    
    % DC-komponentu zachováme — strednú hodnotu neodpočítavame
    N  = length(combined_signal);   % dĺžka signálu
    fs = 500;

    dft_signal   = fft(combined_signal);    % komplexný spektrum (celá frekvenčná os)
    total_energy = sum(abs(dft_signal).^2); % celková energia v spektrálnej oblasti

    % Indexácia pre reálny signál
    dc_idx = 1;
    if mod(N,2) == 0        % ak N je párne
        half = N/2 + 1;     % pravá hranica pre [0..fs/2] (vrátane Nyquist)
    else
        half = (N + 1)/2;   % stred spektra
    end
    pos_idx = 2:half;       % indexy kladných frekvencií (bez DC)

    mags        = abs(dft_signal(pos_idx));     % moduly týchto koeficientov
    [~, order]  = sort(mags, 'descend');        % triedim podľa klesajúcej amplitúdy
    pos_sorted  = pos_idx(order);
    numPos      = numel(pos_sorted);

    % Režim optimalizácie (podľa PRD alebo SNR)
    usePRD = ~isnan(desired_PRD);    % true, ak je stanovený cieľový PRD
    useSNR = ~isnan(desired_SNR);
    if ~(usePRD || useSNR)           % ak nie je nastavené ani jedno, ani druhé, predvolene optimalizujeme PRD
        usePRD     = true;
        desired_PRD = 5;
    end

    % Binárne vyhľadávanie podľa počtu kladných frekvencií
    tol      = 0.01;    % prípustná chyba podľa PRD
    left     = 1;       % ľavá hranica binárneho vyhľadávania
    right    = numPos;  % pravá hranica (maximum všetkých frekvencií)
    best_mid = [];      % zapamätáme si najlepšie číslo koeficientov
    best_PRD = Inf;     % najlepšia hodnota PRD
    best_SNR = -Inf;    % najlepšia hodnota SNR

    while left <= right                           % binárne vyhľadávanie
        mid = floor((left + right) / 2);

        % Maska so symetriou
        dft_thr           = zeros(size(dft_signal));    % vynulujeme celý spektrum
        dft_thr(dc_idx)   = dft_signal(dc_idx);   % DC всегда сохраняем
        keep_pos          = pos_sorted(1:mid);    % ponecháme prvé stredné najvyššie frekvencie

        for k = 1:numel(keep_pos)                 % preberáme vybrané frekvencie
            i = keep_pos(k);
            j = N - i + 2;                        % spriahnutý index pre zápornú frekvenciu
            dft_thr(i) = dft_signal(i);           % save +
            dft_thr(j) = dft_signal(j);           % save -
        end

        recon = real(ifft(dft_thr));        % spätná FFT: rekonštruovaný signál
        err   = combined_signal - recon;    % chyba (rozdiel)

        % Vypočítame PRD a SNR 
        PRD = sqrt(sum(err.^2) / sum(combined_signal.^2)) * 100;
        if sum(err.^2) == 0
            SNR = Inf;
        else
            SNR = 10*log10(sum(combined_signal.^2) / sum(err.^2));
        end

        % Výber najlepšieho mid podľa aktívnej metriky
        if usePRD
            closer = abs(PRD - desired_PRD) < abs(best_PRD - desired_PRD);      % bližšie k cieľu
            tie    = abs(PRD - desired_PRD) == abs(best_PRD - desired_PRD);     % remíza podľa PRD
            better = closer || (tie && SNR > best_SNR);                         % potom sa pozrieme na SNR
            if better
                best_mid = mid;
                best_PRD = PRD;
                best_SNR = SNR;
            end

            cond = (PRD <= desired_PRD + tol);      % dosiahli cieľ v oblasti PRD
            if cond
                right = mid - 1;                    % skúšame ešte menšie koeficienty
            else
                left  = mid + 1;                    % nízka kvalita tak ze berieme viac koeficientov
            end
        else        % režim optimalizácie podľa SNR
            closer = abs(SNR - desired_SNR) < abs(best_SNR - desired_SNR);
            tie    = abs(SNR - desired_SNR) == abs(best_SNR - desired_SNR);
            better = closer || (tie && PRD < best_PRD);
            if better
                best_mid = mid;
                best_PRD = PRD;
                best_SNR = SNR;
            end

            cond = (SNR >= desired_SNR);
            if cond
                right = mid - 1;
            else
                left  = mid + 1;
            end
        end
    end

    % Ak binárne vyhľadávanie nenašlo nič vhodné, vezmeme 90 %
    if isempty(best_mid) || best_mid < 1 || best_mid > numPos
        best_mid = round(0.9 * numPos);
    end

    % Konečná rekonštrukcia podľa best_mid 
    dft_thresholded         = zeros(size(dft_signal));  % vynulujeme všetko
    dft_thresholded(dc_idx) = dft_signal(dc_idx);       % DC ponecháme

    keep_pos = pos_sorted(1:best_mid);      % najlepšie frekvencie
    for k = 1:numel(keep_pos)
        i = keep_pos(k);
        j = N - i + 2;
        dft_thresholded(i) = dft_signal(i);
        dft_thresholded(j) = dft_signal(j);
    end

    reconstructed_signal = real(ifft(dft_thresholded));     % konečný rekonštruovaný signál
    diff_signal          = combined_signal - reconstructed_signal;      % rozdiel

    % Metriky
    PRD_final = sqrt(sum(diff_signal.^2) / sum(combined_signal.^2)) * 100;
    if sum(diff_signal.^2) == 0
        SNR_final = Inf;
    else
        SNR_final = 10*log10(sum(combined_signal.^2) / sum(diff_signal.^2));
    end

    sparsity_orig   = N;        % sparzita originálu = dĺžka signálu
    sparsity_thresh = N - nnz(dft_thresholded);     % koľko koeficientov sa stalo nulovými
    nonzero_k       = nnz(dft_thresholded);         % koľko koeficientov NIE je nula

    if nonzero_k <= 0
        compression = Inf;      % proti deleniu nulou
    else
        compression = N / nonzero_k;        % koeficient kompresie
    end

    energy_retained = sum(abs(dft_thresholded).^2) / max(total_energy, eps) * 100;      % zadržaná energia (%)

    
    % Save results
    app.Signals.Original           = combined_signal;       % výstupný signál
    app.Signals.Reconstructed      = reconstructed_signal;  % rekonštruovaný signál
    app.Signals.Difference         = diff_signal;           % rozdiel
    app.Signals.DFT                = dft_signal;            % celý rozsah
    app.Signals.DFT_Thresholded = dft_thresholded;       % zredukované spektrum

    % Jednostranný rozdielový spektrum (pre graf Rozdiel)
    L          = N;
    diff_fft   = fft(diff_signal);          % FFT z rozdielu
    diff_mag   = abs(diff_fft(1:L/2+1));    % len [0..fs/2]
    freq_axis  = fs * (0:(L/2)) / L;        % frekvenčná os

    app.Signals.FreqAxis           = freq_axis;  % zachovávame os frekvencií
    app.Signals.DifferenceSpectrum = diff_mag;   % spektrum rozdielu


    app.Signals.Metrics.PRD            = PRD_final;
    app.Signals.Metrics.SNR            = SNR_final;
    app.Signals.Metrics.SparsityOrig   = sparsity_orig;
    app.Signals.Metrics.SparsityThresh = sparsity_thresh;
    app.Signals.Metrics.Compression    = compression;
    app.Signals.Metrics.EnergyRetained = energy_retained;
end


% DCT
function computeDCT(app, signal, channel, desired_PRD, desired_SNR)

    max_length = 3000;
    if size(signal,1) < max_length      % ak má signál menej riadkov
        uialert(app.UIFigure, 'Signál je kratší než 3000 vzoriek!', 'Chyba');
        return;
    end

    combined_signal = signal(1:max_length, channel);
    % DC-komponentu zachováme – neodpočítavame priemer
    N = length(combined_signal);

    % Priama DCT transformácia
    dct_signal = dct(combined_signal);  % koeficienty DCT
    total_energy = sum(dct_signal.^2);  % celková energia

    % Triedenie koeficientov podľa amplitúdy
    [~, idx_sorted] = sort(abs(dct_signal), 'descend'); % indexy sú zoradené podľa koeficientu

    % Určujeme režim optimalizácie (PRD alebo SNR)
    usePRD = ~isnan(desired_PRD);
    useSNR = ~isnan(desired_SNR);
    if ~(usePRD || useSNR)
        usePRD = true;
        desired_PRD = 5; 
    end

    % Binárne vyhľadávanie podľa počtu zadaných koeficientov 
    tol = 0.01;   
    left = 1; 
    right = N;
    best_k = N;     % doteraz najlepší počet nenulových koef.
    best_PRD = Inf;
    best_SNR = -Inf;

    while left <= right
        mid = floor((left + right) / 2);

        dct_thr = zeros(size(dct_signal));  % všetky koeficienty = 0
        dct_thr(idx_sorted(1:mid)) = dct_signal(idx_sorted(1:mid)); % ponecháme mid najväčších

        % Zachovávame DC komponent
        dct_thr(1) = dct_signal(1);

        % Rekompozícia (spätná transformácia)
        recon = idct(dct_thr);
        err   = combined_signal - recon;

        % Metrik
        PRD = sqrt(sum(err.^2) / sum(combined_signal.^2)) * 100;
        if sum(err.^2) == 0
            SNR = Inf;
        else
            SNR = 10 * log10(sum(combined_signal.^2) / sum(err.^2));
        end

        % Hľadanie najlepšieho k podľa aktívnej metriky
        if usePRD
            cond = (PRD <= desired_PRD + tol);
            % kontrola, či je tento PRD bližšie k cieľu
            if abs(PRD - desired_PRD) < abs(best_PRD - desired_PRD) || ...
               (abs(PRD - desired_PRD) == abs(best_PRD - desired_PRD) && SNR > best_SNR)
                best_k = nnz(dct_thr);  % počet nenulových koeficientov
                best_PRD = PRD;
                best_SNR = SNR;
            end
            if cond
                right = mid - 1;    % skúšame menší počet koeficientov
            else
                left = mid + 1;     % kvalita je slabá - potrebujeme viac koef.
            end
        else
            cond = (SNR >= desired_SNR);
            if abs(SNR - desired_SNR) < abs(best_SNR - desired_SNR) || ...
               (abs(SNR - desired_SNR) == abs(best_SNR - desired_SNR) && PRD < best_PRD)
                best_k = nnz(dct_thr);
                best_PRD = PRD;
                best_SNR = SNR;
            end
            if cond
                right = mid - 1;
            else
                left = mid + 1;
            end
        end
    end

    % Bezpečná kontrola best_k
    if isempty(best_k) || best_k < 1 || best_k > N
        best_k = round(N * 0.9);
    end

    % Konečná rekonštrukcia
    dct_thresholded = zeros(size(dct_signal));
    dct_thresholded(idx_sorted(1:best_k)) = dct_signal(idx_sorted(1:best_k));
    dct_thresholded(1) = dct_signal(1); % nutne zachováme DC

    reconstructed_signal = idct(dct_thresholded);   % výsledný rekonštruovaný signál
    diff_signal = combined_signal - reconstructed_signal;

    % Metrik
    PRD_final = sqrt(sum(diff_signal.^2) / sum(combined_signal.^2)) * 100;
    if sum(diff_signal.^2) == 0
        SNR_final = Inf;
    else
        SNR_final = 10 * log10(sum(combined_signal.^2) / sum(diff_signal.^2));
    end

    sparsity_orig = N;      % sparsita originálu = dĺžka signálu
    sparsity_thresh = N - nnz(dct_thresholded);     % počet "vynulovaných" koeficientov
    nonzero_k = nnz(dct_thresholded);   % počet nenulových koeficientov

    if nonzero_k <= 0
        compression = Inf;
    else
        compression = N / nonzero_k;    % koeficient kompresie
    end

    energy_retained = sum(dct_thresholded.^2) / max(total_energy, eps) * 100;   % zachovaná energia v %

    % Save results
    app.Signals.Original = combined_signal;
    app.Signals.Reconstructed = reconstructed_signal;
    app.Signals.Difference = diff_signal;
    app.Signals.DCT = dct_signal;
    app.Signals.DCT_Thresholded = dct_thresholded;
    app.Signals.DifferenceSpectrum = abs(dct(diff_signal)); % rozdielový rozsah (pre graf Rozdiel)

    app.Signals.Metrics.PRD = PRD_final;
    app.Signals.Metrics.SNR = SNR_final;
    app.Signals.Metrics.SparsityOrig = sparsity_orig;
    app.Signals.Metrics.SparsityThresh = sparsity_thresh;
    app.Signals.Metrics.Compression = compression;
    app.Signals.Metrics.EnergyRetained = energy_retained;

    % Os koeficientov pre kreslenie DCT spektra
    app.Signals.CoeffAxis = 0:length(dct_signal)-1;
end

% DWT
function computeDWT(app, signal, channel, desired_PRD, desired_SNR, waveletType)
    
    max_length = 3000;
    if size(signal,1) < max_length
        uialert(app.UIFigure, 'Signál je kratší než 3000 vzoriek!', 'Chyba');
        return;
    end

    combined_signal = signal(1:max_length, channel);
    
    N = length(combined_signal);

    % Wavelet rozklad
    maxLevel = 5;   % hĺbka rozkladu
    [C, L] = wavedec(combined_signal, maxLevel, waveletType);
    % C – vektor všetkých koeficientov (aproximácia + detaily)
    % L – vektor dĺžok blokov pre každú úroveň
    total_energy = sum(C.^2);    % celková energia v DWT doméne

    % Triedenie koeficientov
    [~, idx_sorted] = sort(abs(C), 'descend');  % indexy podľa klesajúceho koeficientu

    % Aktívna metrika
    usePRD = ~isnan(desired_PRD);
    useSNR = ~isnan(desired_SNR);
    if ~(usePRD || useSNR)
        usePRD = true;
        desired_PRD = 5;
    end

    % Binárne vyhľadávanie podľa počtu zadaných koeficientov
    tol = 0.01;
    left = 1; right = length(C);
    best_k = right;     % doteraz najlepšia sparsita
    best_PRD = Inf;
    best_SNR = -Inf;

    while left <= right
        mid = floor((left + right) / 2);     % skúšaný počet koeficientov
        C_thr = zeros(size(C));     % vynulujeme všetky koeficienty
        C_thr(idx_sorted(1:mid)) = C(idx_sorted(1:mid));    % ponecháme mid najväčších

        recon = waverec(C_thr, L, waveletType);     % spätná DWT
        recon = recon(1:N);                         % skrátime do N

        diff_signal = combined_signal - recon;  % chyba rekonštrukcie
        
        % Metriky
        PRD = sqrt(sum(diff_signal.^2) / sum(combined_signal.^2)) * 100;
        if sum(diff_signal.^2) == 0
            SNR = Inf;
        else
            SNR = 10 * log10(sum(combined_signal.^2) / sum(diff_signal.^2));
        end

        if usePRD
            cond = (PRD <= desired_PRD + tol);
            if abs(PRD - desired_PRD) < abs(best_PRD - desired_PRD) || ...
               (abs(PRD - desired_PRD) == abs(best_PRD - desired_PRD) && SNR > best_SNR)
                best_k = nnz(C_thr);
                best_PRD = PRD;
                best_SNR = SNR;
            end
            if cond
                right = mid - 1;
            else
                left = mid + 1;
            end
        else
            cond = (SNR >= desired_SNR);
            if abs(SNR - desired_SNR) < abs(best_SNR - desired_SNR) || ...
               (abs(SNR - desired_SNR) == abs(best_SNR - desired_SNR) && PRD < best_PRD)
                best_k = nnz(C_thr);    % počet nenulových koef.
                best_PRD = PRD;
                best_SNR = SNR;
            end
            if cond
                right = mid - 1;
            else
                left = mid + 1;
            end
        end
    end

    % Kontrola best_k
    if isempty(best_k) || best_k < 1 || best_k > length(C)
        best_k = round(length(C) * 0.9);
    end

    % Konečná rekonštrukcia
    C_thr_final = zeros(size(C));
    C_thr_final(idx_sorted(1:best_k)) = C(idx_sorted(1:best_k));
    
    best_recon = waverec(C_thr_final, L, waveletType);
    best_recon = best_recon(1:N);
    diff_signal = combined_signal - best_recon;

    % Metrik
    PRD_final = sqrt(sum(diff_signal.^2) / sum(combined_signal.^2)) * 100;
    if sum(diff_signal.^2) == 0
        SNR_final = Inf;
    else
        SNR_final = 10 * log10(sum(combined_signal.^2) / sum(diff_signal.^2));
    end

    nonzero_k = nnz(C_thr_final);                   % počet nenulových koeficientov
    sparsity_orig   = N;                            % N = 3000
    sparsity_thresh = sparsity_orig - nonzero_k;    % koľko sa "vynulovalo"

    if nonzero_k <= 0
        compression = Inf;
    else
        compression = sparsity_orig / nonzero_k;    % koeficient kompresie
    end

    energy_retained = sum(C_thr_final.^2) / max(total_energy, eps) * 100;   % zachovaná energia v %

    % Normalizujeme názov poľa waveletType
    wname = lower(strtrim(waveletType)); 

    % Vytvorenie štruktúry pre DWT, ak je to potrebné
    if ~isfield(app.Signals, 'DWT')
        app.Signals.DWT = struct();
    end
    if ~isfield(app.Signals.DWT, wname)
        app.Signals.DWT.(wname) = struct();
    end

    % Save results
    app.Signals.DWT.(wname).Original       = combined_signal;
    app.Signals.DWT.(wname).Reconstructed  = best_recon;
    app.Signals.DWT.(wname).Difference     = diff_signal;
    app.Signals.DWT.(wname).Coeffs         = C;
    app.Signals.DWT.(wname).Thresholded    = C_thr_final;
    app.Signals.DWT.(wname).Metrics.PRD    = PRD_final;
    app.Signals.DWT.(wname).Metrics.SNR    = SNR_final;
    app.Signals.DWT.(wname).Metrics.SparsityOrig   = sparsity_orig;
    app.Signals.DWT.(wname).Metrics.SparsityThresh = sparsity_thresh;
    app.Signals.DWT.(wname).Metrics.Compression    = compression;
    app.Signals.DWT.(wname).Metrics.EnergyRetained = energy_retained;

    % Rozsah rozdielov
    diffSpec = abs(wavedec(diff_signal, maxLevel, waveletType));
    
    app.Signals.DWT.(wname).DifferenceSpectrum = diffSpec;

    % Ak ide o prvú transformáciu vôbec, vyplňte základné polia. 
    app.Signals.DWT.(wname).L = L;          % dĺžky blokov na jednotlivých úrovniach
    app.Signals.DWT.(wname).MaxLevel = maxLevel;    % počet úrovní rozkladu


% grafiky a menu môžu fungovať pri prvom spustení
if ~isfield(app.Signals,'Original') || isempty(app.Signals.Original)
    app.Signals.Original      = combined_signal;
    app.Signals.Reconstructed = best_recon;
    app.Signals.Difference    = diff_signal;
end
end 
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZaaanalyzuButton
        function ZaaanalyzuButtonPushed(app, event)
            % Skryjeme StartPanel
            app.Panel.Visible = 'off';  % Skryjeme úvodný panel
        
            % Zobrazujeme MainPanel
            app.Panel_2.Visible = 'on'; % Zobrazíme hlavný panel aplikácie

            % Načítanie databázy
            appFolder = fileparts(mfilename('fullpath'));
            files = dir(fullfile(appFolder, 'matDBs', '*.mat'));
            names = {files.name};
    
            % Extrahujeme čísla z názvu súboru
            numbers = zeros(size(names));
            for k = 1:length(names)
                [numStr, ~] = strtok(names{k}, '.'); % strtok rozdelí reťazec podľa bodky
                numbers(k) = str2double(numStr);     % prevedieme text na číslo
            end

            % Triedenie podľa čísel
            [~, idx] = sort(numbers);   % idx obsahuje usporiadané indexy
            sortedNames = names(idx);   % zoradený zoznam názvov súborov
            
            % Naplnenie rozbaľovacieho zoznamu v GUI 
            app.VbersignluDropDown.Items = sortedNames;  
            
            % ak je zoznam ne-prázdny: automaticky vyberieme prvý súbor
            if ~isempty(sortedNames)
                app.VbersignluDropDown.Value = sortedNames{1}; 
            end
        end

        % Value changed function: VbersignluDropDown
        function VbersignluDropDownValueChanged(app, event)
            selectedFile = app.VbersignluDropDown.Value;

            % Určujeme cestu k priečinku aplikácie
            appFolder = fileparts(mfilename('fullpath'));  
            fullPath = fullfile(appFolder, 'matDBs', selectedFile);

            % Načítanie signálu
            data = load(fullPath);

            if isfield(data, 'ecgSignal')
                app.CurrentSignal = data.ecgSignal; % signál uložíme ako aktuálny
                app.CurrentFile = selectedFile;
                disp(['Načítaný signál z ', selectedFile]);
            else
                uialert(app.UIFigure, ...
                'Vybraný súbor neobsahuje "ecgSignal".', ...
                'Chyba načítania'); 
            end
        end

        % Button pushed function: SpustiButton
        function SpustiButtonPushed(app, event)
            if isempty(app.CurrentSignal)
                uialert(app.UIFigure, 'Najskôr vyberte signál!', 'Chyba');
                return;
            end

            % Inicializácia štruktúry
            % Pri prvom spustení môže byť app.Signals neexistujúca alebo prázdna. V takom prípade vytvoríme novú štruktúru.
            if ~isfield(app, 'Signals') || isempty(app.Signals)
                app.Signals = struct();
            end

            % signál ECG načítaný z vybraného .mat súboru
            signal = app.CurrentSignal; 
            transformType = app.VybertetransformciuDropDown.Value;  % aktuálne zvolený typ transformácie z rozbaľovacieho menu

            % Rozhodovanie medzi PRD a SNR
            selected_PRD = app.VybertePRDEditField.Value;
            desired_SNR  = app.VyberteSNREditField.Value;

            % Ak používateľ práve zmenil jedno z polí, použijeme jeho prioritu
            % Skontrolujeme tiež, či hodnoty nie sú prázdne alebo nulové
            if strcmp(app.LastMetricUsed, 'PRD') && ~isempty(selected_PRD) && selected_PRD > 0
                % priorita PRD
                desired_SNR = NaN;
            elseif strcmp(app.LastMetricUsed, 'SNR') && ~isempty(desired_SNR) && desired_SNR > 0
                % priorita SNR
                selected_PRD = NaN;
            else
                % ak ste nič neměnili alebo sú obe polia prázdne — vyberieme podľa skutočného stavu vyplnenia
            if ~isempty(selected_PRD) && selected_PRD > 0
                app.LastMetricUsed = 'PRD';
                desired_SNR = NaN;
            elseif ~isempty(desired_SNR) && desired_SNR > 0
                app.LastMetricUsed = 'SNR';
                selected_PRD = NaN;
            else
                % ak nie je zadané vôbec nič — predvolené nastavenie PRD
                app.LastMetricUsed = 'PRD';
                desired_SNR = NaN;
            end
            end

            % Výpočet metrík v závislosti od zvolenej transformácie
            switch transformType
                case 'DFT'
                    computeDFT(app, signal, app.VberzvoduSpinner_2.Value, selected_PRD, desired_SNR);
                case 'DCT'
                    computeDCT(app, signal, app.VberzvoduSpinner_2.Value, selected_PRD, desired_SNR);
                case 'DWT'
                    % Vyčistíme staré výsledky DWT
                    app.Signals.DWT = struct();
                    
            % Získavame vybraný kanál
            selectedChannel = app.VberzvoduSpinner_2.Value;

            % Počítame oba wavelet-y
            wavelets = {'haar','db2'};
                
            % Prejdeme všetky wavelety a pre každý zavoláme computeDWT
            for i = 1:length(wavelets)
                computeDWT(app, signal, selectedChannel, selected_PRD, desired_SNR, wavelets{i}); % davame kanal
            end
            end
    
            % Aktualizujeme grafy 
            VybertetypzobrazeniaDropDownOpening(app, []);

        switch transformType
            case {'DFT','DCT'}
                if isfield(app.Signals,'Metrics')
                    app.UITable.Data = { ...
                    app.Signals.Metrics.PRD, ...
                    app.Signals.Metrics.SNR, ...
                    app.Signals.Metrics.SparsityOrig, ...
                    app.Signals.Metrics.SparsityThresh, ...
                    app.Signals.Metrics.Compression, ...
                    app.Signals.Metrics.EnergyRetained ...
                };
                else
                    app.UITable.Data = {[], [], [], [], [], []};
                end

            case 'DWT'
                if isfield(app.Signals,'DWT') && ~isempty(fieldnames(app.Signals.DWT))
                    
                    % vyberieme wavelet, ktorý je vybraný v roletovom menu
                    val = app.VybertetypzobrazeniaDropDown.Value;
                
                if contains(val,'Haar','IgnoreCase',true)
                    sel = 'haar';
                elseif contains(val,'db2','IgnoreCase',true)
                    sel = 'db2';
                else
                    % fallback – vezmeme posledné pole zo štruktúry DWT
                    names = fieldnames(app.Signals.DWT);
                    sel = names{end};
                end

                if isfield(app.Signals.DWT, sel) && isfield(app.Signals.DWT.(sel),'Metrics')
                    m = app.Signals.DWT.(sel).Metrics;
                    app.UITable.Data = { m.PRD, m.SNR, m.SparsityOrig, m.SparsityThresh, m.Compression, m.EnergyRetained };
                else
                    app.UITable.Data = {[], [], [], [], [], []};
                end
                else
                    app.UITable.Data = {[], [], [], [], [], []};
                end
            otherwise
                    app.UITable.Data = {[], [], [], [], [], []};
        end
        end

        % Drop down opening function: VybertetypzobrazeniaDropDown
        function VybertetypzobrazeniaDropDownOpening(app, event)
        % Skontrolujeme, či sú už vypočítané údaje
        if isempty(app.Signals) || ~isfield(app.Signals, 'Original')
            cla(app.UIAxes); 
            cla(app.UIAxes2);
            return;
        end
        
        % Zistíme, aký typ transformácie je aktuálne zvolený v GUI
        transformType = app.VybertetransformciuDropDown.Value;

        % DC koeficienty skrývame iba na grafoch
        hideDC_on_plots = true;

        % Nastavenie položiek v roletovom menu
        switch transformType
            case {'DFT','DCT'}
                items = {'Pôvodný signál','Rekonštruovaný signál','Rozdiel'};
            case 'DWT'
                wavelets  = {'Haar','db2'};
                baseItems = {'Pôvodný signál','Rekonštruovaný signál','Rozdiel'};
                items = cell(1, numel(wavelets)*numel(baseItems));
                idx = 1;
                for w = 1:numel(wavelets)
                    for b = 1:numel(baseItems)
                        items{idx} = [baseItems{b} ' (' wavelets{w} ')'];
                        idx = idx + 1;
                    end
                end
        otherwise
            items = {'Pôvodný signál','Rekonštruovaný signál','Rozdiel'};
        end

        % Priradíme položky do dropdownu
        app.VybertetypzobrazeniaDropDown.Items = items;
    
        if isempty(app.VybertetypzobrazeniaDropDown.Value) || ...
        ~ismember(app.VybertetypzobrazeniaDropDown.Value, items)
            app.VybertetypzobrazeniaDropDown.Value = items{1};
        end
    
        % Aktuálne vybraná položka (napr. "Pôvodný signál (Haar)")
        value = app.VybertetypzobrazeniaDropDown.Value;

        % Opäť kontrola, že máme pôvodný signál – bezpečnostná poistka
        if isempty(app.Signals) || ~isfield(app.Signals,'Original')
            cla(app.UIAxes); 
            cla(app.UIAxes2);
            return;
        end

        % Všeobecné parametre grafov
        fs = 500;
        cla(app.UIAxes); 
        cla(app.UIAxes2);

        % Jednotný štýl grafov
        app.UIAxes.FontName = 'Times New Roman';
        app.UIAxes.FontSize = 12;
        app.UIAxes.LineWidth = 1;

        app.UIAxes2.FontName = 'Times New Roman';
        app.UIAxes2.FontSize = 12;
        app.UIAxes2.LineWidth = 1;

        grid(app.UIAxes,'on')
        grid(app.UIAxes2,'on')

        % Špeciálna obsluha DWT – výber waveletu
        % Pre DWT sú položky typu "Pôvodný signál (Haar)".
        % Potrebujeme z value zistiť názov waveletu a value si "očistiť".
        selectedWavelet = '';
            if strcmp(transformType,'DWT')
                if contains(value,'Haar','IgnoreCase',true)     % Zistíme, či text obsahuje "Haar" alebo "db2"
                    selectedWavelet = 'haar';
                elseif contains(value,'db2','IgnoreCase',true)
                    selectedWavelet = 'db2';
                else
                if isfield(app.Signals,'DWT')    % Fallback – vezmeme posledný uložený wavelet v app.Signals.DWT
                    names = fieldnames(app.Signals.DWT);
                if ~isempty(names)
                    selectedWavelet = names{end};
                end
                end
                end
        % Z value odstránime zátvorku " (Haar)" / " (db2)"
        value = regexprep(value,' \([^\)]+\)','');
            end

        % Inicializácia premenných pre spektrum
        spectrum   = [];
        coeffAxis  = [];
        xlabelText = '';

        % Voľba typu zobrazenia (ľavý + pravý graf)
        switch value
            case 'Pôvodný signál'
                yData = app.Signals.Original;

            switch transformType
                case 'DFT'
                    Y  = fft(yData);
                    L  = numel(Y);
                    magY = abs(Y);
                    spectrum   = magY(1:L/2+1);
                    coeffAxis  = fs * (0:(L/2)) / L;
                    xlabelText = 'Frekvencia (Hz)';

                    % Skrývame DC bin na grafe, aby bol zvyšok lepšie viditeľný
                    if hideDC_on_plots && ~isempty(spectrum)
                        spectrum(1) = 0;
                    end

                case 'DCT'
                    spectrum   = abs(app.Signals.DCT);
                    coeffAxis  = 0:length(spectrum)-1;
                    xlabelText = 'Index koeficientu';
                    
                    if hideDC_on_plots && ~isempty(spectrum)    % Prvý DCT koeficient je DC – vizuálne potlačíme
                        spectrum(1) = 0; 
                    end

                case 'DWT'
                    if isfield(app.Signals,'DWT') && ...
                        isfield(app.Signals.DWT, selectedWavelet)
                        spectrum   = abs(app.Signals.DWT.(selectedWavelet).Coeffs);
                        coeffAxis  = 0:length(spectrum)-1;
                        xlabelText = 'Index koeficientu';

                        % Potlačenie aproximančného bloku (nízkofrekvenčný
                        % DC blok) po L
                        if hideDC_on_plots && ...
                            isfield(app.Signals.DWT.(selectedWavelet),'L') && ...
                           ~isempty(spectrum)
                            
                            Lvec   = app.Signals.DWT.(selectedWavelet).L;
                            cA_len = min(Lvec(1), numel(spectrum));
                            spectrum(1:cA_len) = 0;
                        end
                    end
            end
            titleText = 'Pôvodný signál';

        case 'Rekonštruovaný signál'
            if strcmp(transformType,'DWT') && ...
               isfield(app.Signals,'DWT') && ...
               isfield(app.Signals.DWT, selectedWavelet)
               yData = app.Signals.DWT.(selectedWavelet).Reconstructed;
            else
                yData = app.Signals.Reconstructed;
            end

            switch transformType
                case 'DFT'
                    Y  = fft(yData);
                    L  = numel(Y);
                    spectrum   = abs(Y(1:L/2+1));
                    coeffAxis  = fs * (0:(L/2)) / L;
                    xlabelText = 'Frekvencia (Hz)';

                    if hideDC_on_plots && ~isempty(spectrum)
                        spectrum(1) = 0;
                    end

                case 'DCT'
                    spectrum   = abs(app.Signals.DCT_Thresholded);
                    coeffAxis  = 0:length(spectrum)-1;
                    xlabelText = 'Index koeficientu';
                    if hideDC_on_plots && ~isempty(spectrum)
                        spectrum(1) = 0;
                    end

                case 'DWT'
                    if isfield(app.Signals.DWT, selectedWavelet)
                        spectrum   = abs(app.Signals.DWT.(selectedWavelet).Thresholded);
                        coeffAxis  = 0:length(spectrum)-1;
                        xlabelText = 'Index koeficientu';
                        if hideDC_on_plots && ...
                           isfield(app.Signals.DWT.(selectedWavelet),'L') && ...
                           ~isempty(spectrum)
                            Lvec   = app.Signals.DWT.(selectedWavelet).L;
                            cA_len = min(Lvec(1), numel(spectrum));
                            spectrum(1:cA_len) = 0;
                        end
                    end
            end
            titleText = 'Rekonštruovaný signál';

        case 'Rozdiel'
            if strcmp(transformType,'DWT') && ...
               isfield(app.Signals,'DWT') && ...
               isfield(app.Signals.DWT, selectedWavelet)
                yData = app.Signals.DWT.(selectedWavelet).Difference;
            else
                yData = app.Signals.Difference;
            end

            switch transformType
                case 'DFT'
                    % pouzivame uz vypocitany DifferenceSpectrum + FreqAxis
                    if isfield(app.Signals,'DifferenceSpectrum') && ...
                       isfield(app.Signals,'FreqAxis')
                        spectrum   = app.Signals.DifferenceSpectrum;
                        coeffAxis  = app.Signals.FreqAxis;
                        xlabelText = 'Frekvencia (Hz)';
                    end

                    if hideDC_on_plots && ~isempty(spectrum)
                        spectrum(1) = 0;
                    end

                case 'DCT'
                    if isfield(app.Signals,'DifferenceSpectrum') && ...
                       isfield(app.Signals,'CoeffAxis')
                        spectrum   = app.Signals.DifferenceSpectrum;
                        coeffAxis  = app.Signals.CoeffAxis;
                        xlabelText = 'Index koeficientu';
                    end

                    if hideDC_on_plots && ~isempty(spectrum)
                        spectrum(1) = 0;
                    end

                case 'DWT'
                    if isfield(app.Signals,'DWT') && ...
                       isfield(app.Signals.DWT, selectedWavelet)
                        spectrum   = abs(app.Signals.DWT.(selectedWavelet).DifferenceSpectrum);
                        coeffAxis  = 0:length(spectrum)-1;
                        xlabelText = 'Index koeficientu';

                        if hideDC_on_plots && ...
                           isfield(app.Signals.DWT.(selectedWavelet),'L') && ...
                           ~isempty(spectrum)
                           Lvec   = app.Signals.DWT.(selectedWavelet).L;
                           cA_len = min(Lvec(1), numel(spectrum));
                           spectrum(1:cA_len) = 0;
                        end
                    end
            end

            titleText = 'Rozdiel';
        end

    % Kreslenie ľavého grafu – časová oblasť
    plot(app.UIAxes, 0:length(yData)-1, yData, 'b', 'LineWidth', 1.5);
    ylabel(app.UIAxes, 'Amplitúda (mV)', 'FontWeight', 'bold');
    xlabel(app.UIAxes, 'Vzorky', 'FontWeight', 'bold');
    title(app.UIAxes, titleText);
    xlim(app.UIAxes, [0 length(yData)]);
    grid(app.UIAxes, 'on');

    % Kreslenie pravého grafu – spektrum
    if ~isempty(spectrum) && ~isempty(coeffAxis)
        spectrum = max(spectrum, 0);   % Bezpečnostne odstránime prípadné záporné numerické šumy

        y = spectrum(:);
        x = coeffAxis(:);

        % Amplitúdový prah – vizuálne ignorujeme koeficienty < 1 % maxima
        floorVal = 0.01 * max(y); 
        keepMask = (y >= floorVal);

        % Zostávajú len "viditeľné" koeficienty
        xVis = x(keepMask);
        yVis = y(keepMask);

        % stem-graf pre spektrum
        cla(app.UIAxes2);
        h = stem(app.UIAxes2, xVis, yVis, 'r');
        set(h, 'Marker','.', 'LineWidth',1.0, 'MarkerSize',4, 'BaseValue',0);

        xlabel(app.UIAxes2, xlabelText, 'FontWeight', 'bold');
        ylabel(app.UIAxes2, 'Amplitúda (norm.)', 'FontWeight', 'bold');

        if strcmp(transformType,'DWT')
            title(app.UIAxes2, ...
                sprintf('Amplitúdové spektrum (%s – %s)', ...
                upper(transformType), upper(selectedWavelet)), ...
                'FontWeight','bold');
        else
            title(app.UIAxes2, ...
                sprintf('Amplitúdové spektrum (%s)', upper(transformType)), ...
                'FontWeight','bold');
        end

        % Nastavenie rozsahov osí
        switch transformType
            case 'DFT'
                % Pre DFT zobrazujeme frekvencie 0..fs/2
                xlim(app.UIAxes2, [0 fs/2]);
                xticks(app.UIAxes2, 0:20:fs/2);
                xlabel(app.UIAxes2, 'Frekvencia (Hz)', 'FontWeight', 'bold');
            case {'DCT','DWT'}
                % Pre DCT/DWT zobrazujeme index koeficientu
                xlim(app.UIAxes2, [0 length(spectrum)]);
                xticks(app.UIAxes2, 0:500:length(spectrum));
                xlabel(app.UIAxes2, 'Index koeficientu', 'FontWeight', 'bold');
        end

        grid(app.UIAxes2, 'on');
    else
        cla(app.UIAxes2);
    end
        end

        % Value changed function: VybertePRDEditField
        function VybertePRDEditFieldValueChanged(app, event)
           % Získa novú hodnotu od používateľa
            newVal = app.VybertePRDEditField.Value;
        
            % Ak je hodnota prázdna alebo NaN → doplníme správnu defaultnú hodnotu
            if isempty(newVal) || isnan(newVal)
                app.VybertePRDEditField.Value = 3; % bezpečná predvolená hodnota
            end
    
            % Nastavíla prioritu – používateľ práve upravil PRD
            app.LastMetricUsed = 'PRD';
    
            % Aktualizácia GUI 
            drawnow;
        end

        % Value changed function: VyberteSNREditField
        function VyberteSNREditFieldValueChanged(app, event)
            % Získame novú hodnotu
            newVal = app.VyberteSNREditField.Value;
    
            % Kontrola hodnoty – ak neplatná, nastavíme default
            if isempty(newVal) || isnan(newVal)
                app.VyberteSNREditField.Value = 15; 
            end
    
            app.LastMetricUsed = 'SNR';
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_2 = uipanel(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.Panel_2);
            app.VsledkyPanel = uipanel(app.GridLayout2);
            app.VsledkyPanel.Title = 'Výsledky';
            app.GridLayout4 = uigridlayout(app.VsledkyPanel);
            app.UIAxes = uiaxes(app.GridLayout4);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'PRD'; 'SNR'; 'Sparzita (pôvodné)'; 'Sparzita (po prahovaní)'; 'Kompresia'; 'Energia'};
            app.UITable.RowName = {};
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_3);
            app.VberzvoduSpinner_2Label = uilabel(app.GridLayout3);
            app.VberzvoduSpinner_2Label.Text = 'Výber zvodu';
            app.VberzvoduSpinner_2 = uispinner(app.GridLayout3);
            app.VberzvoduSpinner_2.Limits = [1 12];
            app.VberzvoduSpinner_2.Value = 1;
            app.SpustiButton = uibutton(app.GridLayout3, 'push');
            app.SpustiButton.ButtonPushedFcn = createCallbackFcn(app, @SpustiButtonPushed, true);
            app.SpustiButton.Text = 'Spustiť';
            app.VybertePRDEditFieldLabel = uilabel(app.GridLayout3);
            app.VybertePRDEditFieldLabel.Text = 'Vyberte PRD';
            app.VybertePRDEditField = uieditfield(app.GridLayout3, 'numeric');
            app.VybertePRDEditField.Limits = [3 7];
            app.VybertePRDEditField.ValueChangedFcn = createCallbackFcn(app, @VybertePRDEditFieldValueChanged, true);
            app.VybertePRDEditField.Value = 3;
            app.VyberteSNREditFieldLabel = uilabel(app.GridLayout3);
            app.VyberteSNREditFieldLabel.Text = 'Vyberte SNR';
            app.VyberteSNREditField = uieditfield(app.GridLayout3, 'numeric');
            app.VyberteSNREditField.Limits = [15 25];
            app.VyberteSNREditField.ValueChangedFcn = createCallbackFcn(app, @VyberteSNREditFieldValueChanged, true);
            app.VyberteSNREditField.Value = 15;
            app.VybertetransformciuDropDownLabel = uilabel(app.GridLayout3);
            app.VybertetransformciuDropDownLabel.Text = 'Vyberte transformáciu';
            app.VybertetransformciuDropDown = uidropdown(app.GridLayout3);
            app.VybertetransformciuDropDown.Items = {'DFT', 'DCT', 'DWT'};
            app.VybertetransformciuDropDown.Value = 'DFT';
            app.VbersignluDropDownLabel = uilabel(app.GridLayout3);
            app.VbersignluDropDownLabel.Text = 'Výber signálu';
            app.VbersignluDropDown = uidropdown(app.GridLayout3);
            app.VbersignluDropDown.Items = {''};
            app.VbersignluDropDown.ValueChangedFcn = createCallbackFcn(app, @VbersignluDropDownValueChanged, true);
            app.VbersignluDropDown.Value = '';
            app.VybertetypzobrazeniaDropDownLabel = uilabel(app.GridLayout3);
            app.VybertetypzobrazeniaDropDownLabel.Text = 'Vyberte typ zobrazenia';
            app.VybertetypzobrazeniaDropDown = uidropdown(app.GridLayout3);
            app.VybertetypzobrazeniaDropDown.Items = {'Pôvodný signál', 'Rekonštruovaný signál', 'Rozdiel'};
            app.VybertetypzobrazeniaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @VybertetypzobrazeniaDropDownOpening, true);
            app.VybertetypzobrazeniaDropDown.Value = 'Pôvodný signál';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.BakalrskaprcaAnalzasparsitysignlovvDOTLabel = uilabel(app.GridLayout);
            app.BakalrskaprcaAnalzasparsitysignlovvDOTLabel.Text = 'Bakalárska práca: Analýza sparsity signálov v DOT';
            app.DFTDCTDWTporovnaniesparsityakvalityrekontrukcieLabel = uilabel(app.GridLayout);
            app.DFTDCTDWTporovnaniesparsityakvalityrekontrukcieLabel.Text = 'DFT, DCT, DWT – porovnanie sparsity a kvality rekonštrukcie';
            app.VypracovalaAnzhelikaNikolaieva2026Label = uilabel(app.GridLayout);
            app.VypracovalaAnzhelikaNikolaieva2026Label.Text = 'Vypracovala: Anzhelika Nikolaieva, 2026';
            app.ZaaanalyzuButton = uibutton(app.GridLayout, 'push');
            app.ZaaanalyzuButton.ButtonPushedFcn = createCallbackFcn(app, @ZaaanalyzuButtonPushed, true);
            app.ZaaanalyzuButton.Text = 'Začať analýzu';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = number_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end